classdef WaveTankApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        WaveControlTab           matlab.ui.container.Tab
        WaveTypeButtonGroup      matlab.ui.container.ButtonGroup
        Button3                  matlab.ui.control.RadioButton
        Button2                  matlab.ui.control.RadioButton
        TypButton                matlab.ui.control.RadioButton
        PowerSlider              matlab.ui.control.Slider
        StopButton               matlab.ui.control.Button
        StartButton              matlab.ui.control.Button
        ControlWave              matlab.ui.control.UIAxes
        WaveAnalysisTab          matlab.ui.container.Tab
        AnalysisWave             matlab.ui.control.UIAxes
        WaveCalibrationTab       matlab.ui.container.Tab
        ColorThresholdingLabel   matlab.ui.control.Label
        DownSlider               matlab.ui.control.Slider
        DownSliderLabel          matlab.ui.control.Label
        UpSlider                 matlab.ui.control.Slider
        UpSliderLabel            matlab.ui.control.Label
        CropLabel                matlab.ui.control.Label
        RightSlider              matlab.ui.control.Slider
        RightSliderLabel         matlab.ui.control.Label
        LeftSlider               matlab.ui.control.Slider
        LeftSliderLabel          matlab.ui.control.Label
        EditedCalibrationWave    matlab.ui.control.UIAxes
        OriginalCalibrationWave  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Reading the video and starting the videoplayer
            originalVideo = vision.VideoFileReader("wave_03.mov")
            videoPlayer = vision.VideoPlayer;
            i = 0; 
            while 0 < 1
                % Showing the original, unedited video
                videoPlayer(originalVideo)
                imshow(originalVideo(), 'Parent', app.OriginalCalibrationWave)

                %% Showing and editing the edited video
                    %Creates the mask of the wave
                BWWave = edge(createMask(imgaussfilt(imcrop(originalVideo(), [500,600,1100,275]),3)), 'canny');
                    %No idea what this is for and what it does but it works
                row = nan(1,size(BWWave,2));
                [heightIMG,widthIMG] = size(BWWave);
                line = zeros(heightIMG, widthIMG);

                js = zeros(1, widthIMG-2);
                ls = zeros(1, widthIMG-2);
                for j = 1:widthIMG; 
                    for l = 1:heightIMG %figure out how to get height
                        if BWWave(l,j) == 1
                            line(l, j) = 1;
                            js(j-1) = j;
                            ls(j-1) = l;
                            break
                        end
                    end
                end
                row = fillmissing(row, 'previous');
                % Finding height of calm water
                for k = 1:widthIMG; 
                    try 
                        row(k) = find(BWWave(:,k),1,'first');
                        [lowY, corX] = min(row);
                        [highY, corXHigh] = max(row)
                    end    
                end
                calmLvl = lowY

                editedVideo = BWWave + imcrop(originalVideo(), [500,600,1100,275]);
                editedVideo = insertShape(editedVideo, 'circle', [corX lowY 5], 'LineWidth', 10);
                editedVideo = insertShape(editedVideo,'line', [0 calmLvl widthIMG+1 calmLvl], 'LineWidth', 2, 'Color', 'red');
                imshow(editedVideo, 'Parent', app.AnalysisWave)
                imshow(editedVideo, 'Parent', app.ControlWave)

                if isDone(originalVideo) % Restarts the videoplayer when it finishes
                    originalVideo = vision.VideoFileReader("wave_03.mov")
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.WaveControlTab = uitab(app.TabGroup);
            app.WaveControlTab.Title = 'Wave Control';
            app.ControlWave = uiaxes(app.WaveControlTab);
            title(app.ControlWave, 'Wave')
            app.ControlWave.XTick = [];
            app.ControlWave.YTick = [];
            app.StartButton = uibutton(app.WaveControlTab, 'push');
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.WaveControlTab, 'push');
            app.StopButton.Text = 'Stop';
            app.PowerSlider = uislider(app.WaveControlTab);
            app.WaveTypeButtonGroup = uibuttongroup(app.WaveControlTab);
            app.WaveTypeButtonGroup.Title = 'Wave Type';
            app.TypButton = uiradiobutton(app.WaveTypeButtonGroup);
            app.TypButton.Text = 'Typ';
            app.TypButton.Value = true;
            app.Button2 = uiradiobutton(app.WaveTypeButtonGroup);
            app.Button2.Text = 'Button2';
            app.Button3 = uiradiobutton(app.WaveTypeButtonGroup);
            app.Button3.Text = 'Button3';
            app.WaveAnalysisTab = uitab(app.TabGroup);
            app.WaveAnalysisTab.Title = 'Wave Analysis';
            app.AnalysisWave = uiaxes(app.WaveAnalysisTab);
            title(app.AnalysisWave, 'Wave')
            app.AnalysisWave.XTick = [];
            app.AnalysisWave.YTick = [];
            app.WaveCalibrationTab = uitab(app.TabGroup);
            app.WaveCalibrationTab.Title = 'Wave Calibration';
            app.OriginalCalibrationWave = uiaxes(app.WaveCalibrationTab);
            title(app.OriginalCalibrationWave, 'Original Wave')
            app.OriginalCalibrationWave.XTick = [];
            app.OriginalCalibrationWave.YTick = [];
            app.EditedCalibrationWave = uiaxes(app.WaveCalibrationTab);
            title(app.EditedCalibrationWave, 'Edited Wave')
            app.EditedCalibrationWave.XTick = [];
            app.EditedCalibrationWave.YTick = [];
            app.LeftSliderLabel = uilabel(app.WaveCalibrationTab);
            app.LeftSliderLabel.Text = 'Left';
            app.LeftSlider = uislider(app.WaveCalibrationTab);
            app.RightSliderLabel = uilabel(app.WaveCalibrationTab);
            app.RightSliderLabel.Text = 'Right';
            app.RightSlider = uislider(app.WaveCalibrationTab);
            app.CropLabel = uilabel(app.WaveCalibrationTab);
            app.CropLabel.Text = 'Crop';
            app.UpSliderLabel = uilabel(app.WaveCalibrationTab);
            app.UpSliderLabel.Text = 'Up';
            app.UpSlider = uislider(app.WaveCalibrationTab);
            app.DownSliderLabel = uilabel(app.WaveCalibrationTab);
            app.DownSliderLabel.Text = 'Down';
            app.DownSlider = uislider(app.WaveCalibrationTab);
            app.ColorThresholdingLabel = uilabel(app.WaveCalibrationTab);
            app.ColorThresholdingLabel.Text = 'Color Thresholding';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WaveTankApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end